classdef Backscatter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UnderwaterBackscatterLinkBudgetUIFigure  matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        CalculateButton               matlab.ui.control.Button
        AnalysisPanel                 matlab.ui.container.Panel
        VsButtonGroup                 matlab.ui.container.ButtonGroup
        P_inButton                    matlab.ui.control.RadioButton
        RangeButton                   matlab.ui.control.RadioButton
        FrequencyButton               matlab.ui.control.RadioButton
        PlotButtonGroup               matlab.ui.container.ButtonGroup
        MinP_inButton                 matlab.ui.control.RadioButton
        MaxRangeButton                matlab.ui.control.RadioButton
        HarvPowSNRButton              matlab.ui.control.RadioButton
        Panel                         matlab.ui.container.Panel
        VsLabel                       matlab.ui.control.Label
        ResultsPanel                  matlab.ui.container.Panel
        uplinkPlot                    matlab.ui.control.UIAxes
        downlinkPlot                  matlab.ui.control.UIAxes
        PhysicalSetupPanel            matlab.ui.container.Panel
        NodeDropDown                  matlab.ui.control.DropDown
        BackscatternodeDropDownLabel  matlab.ui.control.Label
        RxDropDown                    matlab.ui.control.DropDown
        RxDropDownLabel               matlab.ui.control.Label
        TxDropDown                    matlab.ui.control.DropDown
        TxDropDownLabel               matlab.ui.control.Label
        ConfigChannelButton           matlab.ui.control.Button
        ConfigNodeButton              matlab.ui.control.Button
        ConfigRxButton                matlab.ui.control.Button
        ConfigTxButton                matlab.ui.control.Button
        GraphicsPanel                 matlab.ui.container.Panel
        PinLabel                      matlab.ui.control.Label
        BLLabel                       matlab.ui.control.Label
        SLLabel                       matlab.ui.control.Label
        RLLabel                       matlab.ui.control.Label
        SNRLabel                      matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        HarvestedPowerLabel           matlab.ui.control.Label
        AnalysisSettingsPanel         matlab.ui.container.Panel
        RangeUnitDropDown             matlab.ui.control.DropDown
        PowerUnitDropDown             matlab.ui.control.DropDown
        FreqUnitDropDown              matlab.ui.control.DropDown
        InputPowerEditField           matlab.ui.control.NumericEditField
        PowerLabel                    matlab.ui.control.Label
        RangeEditField                matlab.ui.control.NumericEditField
        FrequencyEditField_2Label_2   matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.NumericEditField
        FrequencyLabel                matlab.ui.control.Label
        OperationSettingsPanel        matlab.ui.container.Panel
        PowerThresholdUnitDropDown    matlab.ui.control.DropDown
        BandwidthUnitDropDown         matlab.ui.control.DropDown
        SNRThresholdEditField         matlab.ui.control.NumericEditField
        SNRThresholdLabel             matlab.ui.control.Label
        InputPowerEditFieldLabel_10   matlab.ui.control.Label
        PowerThresholdEditField       matlab.ui.control.NumericEditField
        PowerThresholdLabel           matlab.ui.control.Label
        DataBandwidthEditField        matlab.ui.control.NumericEditField
        DataBandwidthLabel            matlab.ui.control.Label
    end

    
    properties (Access = public)
        model %Theoretical model object
        transDialog % Transducer dialog
        analysisSettings % Transmitter settings
        backscatterSettings % backscatter node settings
        builtIntransducers=["Spherical","Custom"];
        folder

    end
    
    methods (Access = public)
        
        function results = updatePlots(app)
            
            P_th=app.backscatterSettings.PowerThreshold;
            SNR_th=app.backscatterSettings.SNRThreshold;
            BW=app.backscatterSettings.BW;

            if ~app.checkFreqOverlap(app.model.tx.freq,app.model.rx.freq)
                errordlg("The frequency range of Tx and Rx do not overlap! Please choose transducers with compatible operating frequencies.");
                return
            end

            if ~app.checkFreqOverlap(app.model.tx.freq,app.model.node.freq)
                errordlg("The frequency range of Tx and node do not overlap! Please choose transducers with compatible operating frequencies.");
                return
            end

            validFreqRange=app.model.getFreqRange();

            if app.analysisSettings.f>=validFreqRange(1) && app.analysisSettings.f<=validFreqRange(2)
                app.FrequencyEditField.BackgroundColor=[1 1 1];
            end


            switch app.VsButtonGroup.SelectedObject

                case app.FrequencyButton
                    range=app.analysisSettings.Range;
                    freq=logspace(log10(validFreqRange(1)),log10(validFreqRange(2)),10000);
                    Pin=app.analysisSettings.Pin;
                    xVar=freq;
                    xUnit=app.FreqUnitDropDown.Value;
                    
                case app.RangeButton
                    range=logspace(0,4,1e4);
                    freq=app.analysisSettings.f;
                    Pin=app.analysisSettings.Pin;
                    xVar=range;
                    xUnit=app.RangeUnitDropDown.Value;
                    
                    if freq<validFreqRange(1) || freq>validFreqRange(2)
                        app.FrequencyEditField.BackgroundColor=[255 87 51]/255;
                        errordlg("Invalid target frequency. Please select a frequency between "+validFreqRange(1)/1000+" and "+validFreqRange(2)/1000+" kHz.");
                    end


                case app.P_inButton
                    range=app.analysisSettings.Range;
                    freq=app.analysisSettings.f;
                    Pin=logspace(-1,4,1e4);
                    xVar=Pin;
                    xUnit=app.PowerUnitDropDown.Value;
                    if freq<validFreqRange(1) || freq>validFreqRange(2)
                        app.FrequencyEditField.BackgroundColor=[255 87 51]/255;
                        errordlg("Invalid target frequency. Please select a frequency between "+validFreqRange(1)/1000+" and "+validFreqRange(2)/1000+" kHz.");
                    end
            end

            switch app.PlotButtonGroup.SelectedObject
                case app.HarvPowSNRButton

                    P_h=app.model.calculateHarvestedPower(freq,range,Pin);
                    SNR=app.model.calculateSNR(freq,range,Pin,BW);


                    plot(app.downlinkPlot,...
                        app.dispUnit(xVar,xUnit),...
                        app.dispUnit(P_h,app.PowerThresholdUnitDropDown.Value),"LineWidth",1.5);

                    hold(app.downlinkPlot,"on")
                                        
                    plot(app.uplinkPlot,....
                        app.dispUnit(xVar,xUnit),...
                        SNR,"LineWidth",1.5);

                    hold(app.uplinkPlot,"on")

                    
                    plot(app.downlinkPlot,...
                            app.dispUnit(xVar,xUnit),...
                            app.dispUnit(xVar./xVar*app.backscatterSettings.PowerThreshold,app.PowerThresholdUnitDropDown.Value) ...
                            ,"--","LineWidth",1.5);

                    hold(app.downlinkPlot,"off")
    
                    plot(app.uplinkPlot,...
                        app.dispUnit(xVar,xUnit),...
                        xVar./xVar*app.backscatterSettings.SNRThreshold ...
                        ,"--","LineWidth",1.5);
                    
                    hold(app.uplinkPlot,"off")


                case app.MinP_inButton
                    
                    Pin_d=app.model.calculateMinPowerInDownlink(freq,range,P_th);
                    Pin_u=app.model.calculateMinPowerInUplink(freq,range,BW,SNR_th);

                    plot(app.downlinkPlot,...
                        app.dispUnit(xVar,xUnit),...
                        app.dispUnit(Pin_d,app.PowerUnitDropDown.Value),"LineWidth",1.5);
                    hold(app.downlinkPlot,"off")

                    plot(app.uplinkPlot,....
                        app.dispUnit(xVar,xUnit),...
                        app.dispUnit(Pin_u,app.PowerUnitDropDown.Value),"LineWidth",1.5);
                    hold(app.uplinkPlot,"off")
                case app.MaxRangeButton

                    range_d=app.model.calculateMaxRangeDownlink(freq,Pin,P_th);
                    range_u=app.model.calculateMaxRangeUplink(freq,Pin,SNR_th,BW);


                    plot(app.downlinkPlot,...
                        app.dispUnit(xVar,xUnit),...
                        app.dispUnit(range_d,app.RangeUnitDropDown.Value),"LineWidth",1.5);
                    hold(app.downlinkPlot,"off")
                    
                    plot(app.uplinkPlot,....
                        app.dispUnit(xVar,xUnit),...
                        app.dispUnit(range_u,app.RangeUnitDropDown.Value),"LineWidth",1.5);
                    hold(app.uplinkPlot,"off")
            end

        end
        
            function results = dispUnit(app,x,unit)
                switch unit
                    case "m"
                        results=x;
                    case "km"
                        results=x/1000;
                    case "ft"
                        results=x*3.28084;
                    case "mile"
                        results=x/1609;
                    case "W"
                        results=x;
                    case "mW"
                        results=x*1000;
                    case "uW"
                        results=x*1e6;
                    case "dBW"
                        results=10*log10(x);
                    case "dBm"
                        results=10*log10(x*1000);
                    case "Hz"
                        results=x;
                    case "kHz"
                        results=x/1000;
                    case "MHz"
                        results=x/1e6;
                end
        
            end
            function results = adjustUnit(app,x,unit)
                switch unit
                    case "m"
                        results=x;
                    case "km"
                        results=x*1000;
                    case "ft"
                        results=x/3.28084;
                    case "mile"
                        results=x*1609;
                    case "W"
                        results=x;
                    case "mW"
                        results=x/1000;
                    case "uW"
                        results=x/1e6;
                    case "dBW"
                        results=10^(x/10);
                    case "dBm"
                        results=10^(x/10)/1000;
                    case "Hz"
                        results=x;
                    case "kHz"
                        results=x*1000;
                    case "MHz"
                        results=x*1e6;
                end
        
            end


    end
    
    methods (Access = private)


        function results = updateSettings(app)
            app.analysisSettings.Pin=app.adjustUnit(app.InputPowerEditField.Value,app.PowerUnitDropDown.Value);
            app.analysisSettings.f=app.adjustUnit(app.FrequencyEditField.Value,app.FreqUnitDropDown.Value);
            app.analysisSettings.Range=app.adjustUnit(app.RangeEditField.Value,app.RangeUnitDropDown.Value);
            
            app.backscatterSettings.BW=app.adjustUnit(app.DataBandwidthEditField.Value,app.BandwidthUnitDropDown.Value);
            app.backscatterSettings.PowerThreshold=app.adjustUnit(app.PowerThresholdEditField.Value,app.PowerThresholdUnitDropDown.Value);
            app.backscatterSettings.SNRThreshold=app.SNRThresholdEditField.Value;
        end

        function loadTransducerDefaults(app)

            % Get a list of commercial transducer CSV files
            fileList=dir(app.folder+"\*.csv");
            fileNames = string({fileList.name});
            [~, fileNamesWoutExt, ~] = cellfun(@fileparts, fileNames, 'UniformOutput', false);
            commTransducers = string(fileNamesWoutExt);
            
            
            transducerList= [ app.builtIntransducers commTransducers];
            app.TxDropDown.Items=transducerList;
            app.RxDropDown.Items=transducerList;
            app.NodeDropDown.Items=transducerList;
        end
        
        function updatePlotLabels(app)
            
            switch app.PlotButtonGroup.SelectedObject
                case app.HarvPowSNRButton
                    ylabel(app.downlinkPlot, "Harvested Power ["+app.PowerThresholdUnitDropDown.Value+"]");
                    pUnit=app.PowerThresholdUnitDropDown.Value;
                    if strcmp(pUnit,"W") || strcmp(pUnit,"mW") || strcmp(pUnit,"uW")
                        app.downlinkPlot.YScale = "log";
                    else
                        app.downlinkPlot.YScale = "linear";
                    end

                    ylabel(app.uplinkPlot, "SNR [dB]");
                    app.uplinkPlot.YScale = "linear";
                case app.MinP_inButton
                    ylabel(app.downlinkPlot,"Min Input Power ["+app.PowerUnitDropDown.Value+"]");
                    pUnit=app.PowerUnitDropDown.Value;
                    if strcmp(pUnit,"W") || strcmp(pUnit,"mW") || strcmp(pUnit,"uW")
                        app.downlinkPlot.YScale = "log";
                        app.uplinkPlot.YScale = "log";
                    else
                        app.downlinkPlot.YScale = "linear";
                        app.uplinkPlot.YScale = "linear";
                    end

                    ylabel(app.uplinkPlot, "Min Input Power ["+app.PowerUnitDropDown.Value+"]");
                    
                case app.MaxRangeButton
                    ylabel(app.downlinkPlot, "Max Range ["+app.RangeUnitDropDown.Value+"]");
                    app.downlinkPlot.YScale = "log";

                    ylabel(app.uplinkPlot, "Max Range ["+app.RangeUnitDropDown.Value+"]");
                    app.uplinkPlot.YScale = "log";
            end

            switch app.VsButtonGroup.SelectedObject
                case app.P_inButton
                    xlabel(app.downlinkPlot, "Input Power ["+app.PowerUnitDropDown.Value+"]");
                    xlabel(app.uplinkPlot, "Input Power ["+app.PowerUnitDropDown.Value+"]");

                    pUnit=app.PowerUnitDropDown.Value;
                    if strcmp(pUnit,"W") || strcmp(pUnit,"mW") || strcmp(pUnit,"uW")
                        app.downlinkPlot.XScale = "log";
                        app.uplinkPlot.XScale = "log";
                    else
                        app.downlinkPlot.XScale = "linear";
                        app.uplinkPlot.XScale = "linear";
                    end


                case app.RangeButton
                    xlabel(app.downlinkPlot, "Range ["+app.RangeUnitDropDown.Value+"]");
                    app.downlinkPlot.XScale = "log";


                    xlabel(app.uplinkPlot, "Range ["+app.RangeUnitDropDown.Value+"]");
                    app.uplinkPlot.XScale = "log";

                case app.FrequencyButton
                    xlabel(app.downlinkPlot, "Frequency ["+app.FreqUnitDropDown.Value+"]");      
                    app.downlinkPlot.XScale = "log";


                    xlabel(app.uplinkPlot, "Frequency ["+app.FreqUnitDropDown.Value+"]");
                    app.uplinkPlot.XScale = "log";

            end

            if app.PlotButtonGroup.SelectedObject==app.MaxRangeButton || app.VsButtonGroup.SelectedObject==app.RangeButton
                app.downlinkPlot.Title.String = "Power-up Range";
                app.uplinkPlot.Title.String = "Communication Range";
            else
                app.uplinkPlot.Title.String = "Uplink";
                app.downlinkPlot.Title.String = "Downlink";
            end    
            
        end
        
        function overlap= checkFreqOverlap(app,f1,f2)
            temp=interp1(f1,f1,f2);
            overlap=~(sum(isnan(temp))==length(temp));  
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    		if isdeployed 
			    % User is running an executable in standalone mode. 
			    [~ , result] = system('set PATH');
			    directory = string(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
	        else
			    % User is running an m-file from the MATLAB integrated development environment (regular MATLAB).
                tmp_path = mfilename('fullpath');
                [directory , ~ , ~] = fileparts(tmp_path);
                directory=string(directory);
            end
            app.folder=directory+"\Transducers";
            app.model = AcousticBackscatterModel();
            app.model.loadChannelDefaults();
            app.loadTransducerDefaults();
            app.updateSettings();
        end

        % Button pushed function: ConfigTxButton
        function ConfigTxButtonPushed(app, event)
            if strcmp(app.TxDropDown.Value,"Custom")
                dialog=GenericTransducerDialog(app,"tx");
            else
                transDialog=TransducerDialog(app,"tx");
            end
        end

        % Button pushed function: ConfigChannelButton
        function ConfigChannelButtonPushed(app, event)
            channelDialog=ChannelDialog(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.updateSettings();
            app.updatePlotLabels();
            app.updatePlots();
        end

        % Button pushed function: ConfigNodeButton
        function ConfigNodeButtonPushed(app, event)
            if strcmp(app.NodeDropDown.Value,"Custom")
                dialog=GenericTransducerDialog(app,"node");
            else
                transDialog=TransducerDialog(app,"node");
            end
        end

        % Button pushed function: ConfigRxButton
        function ConfigRxButtonPushed(app, event)
            if strcmp(app.RxDropDown.Value,"Custom")
                dialog=GenericTransducerDialog(app,"rx");
            else
                transDialog=TransducerDialog(app,"rx");
            end
        end

        % Value changed function: TxDropDown
        function TxDropDownValueChanged(app, event)
            value = app.TxDropDown.Value;
            if strcmp(value,"Spherical")
                app.ConfigTxButton.Visible="off";
            else 
                app.ConfigTxButton.Visible="on";
                app.ConfigTxButton.Enable="on";
            end
            
            if strcmp(value,"Custom")
                app.ConfigTxButton.Text="Config";
            else
                app.ConfigTxButton.Text="View";
            end

            if ~strcmp(value,"Spherical")&&~strcmp(value,"Custom")       
                app.model.loadTransducer("tx",app.folder,value);
            else
                app.model.createSphericalTransducer("tx");
            end

        end

        % Value changed function: NodeDropDown
        function NodeDropDownValueChanged(app, event)
            value = app.NodeDropDown.Value;
             if strcmp(value,"Spherical")
                app.ConfigNodeButton.Visible="off";
            else 
                app.ConfigNodeButton.Visible="on";
                app.ConfigNodeButton.Enable="on";
            end
            
            if strcmp(value,"Custom")
                app.ConfigNodeButton.Text="Config";
            else
                app.ConfigNodeButton.Text="View";
            end

            if ~strcmp(value,"Spherical")&&~strcmp(value,"Custom")       
                app.model.loadTransducer("node",app.folder,value);
            else
                app.model.createSphericalTransducer("node");
            end
        end

        % Value changed function: RxDropDown
        function RxDropDownValueChanged(app, event)
            value = app.RxDropDown.Value;
             if strcmp(value,"Spherical")
                app.ConfigRxButton.Visible="off";
            else 
                app.ConfigRxButton.Visible="on";
                app.ConfigRxButton.Enable="on";
            end
            
            if strcmp(value,"Custom")
                app.ConfigRxButton.Text="Config";
            else
                app.ConfigRxButton.Text="View";
            end

            if ~strcmp(value,"Spherical")&&~strcmp(value,"Custom")       
                app.model.loadTransducer("rx",app.folder,value);
            else
                app.model.createSphericalTransducer("rx");
            end
        end

        % Selection changed function: VsButtonGroup
        function VsButtonGroupSelectionChanged(app, event)
            selectedButton = app.VsButtonGroup.SelectedObject;
            for button = app.PlotButtonGroup.Buttons
                button.Enable="on";
            end

            app.FrequencyEditField.Enable="on";
            app.RangeEditField.Enable="on";
            app.InputPowerEditField.Enable="on";

            switch selectedButton
                case app.FrequencyButton
                    app.FrequencyEditField.Enable="off";
                case app.RangeButton
                    app.MaxRangeButton.Enable="off";
                    app.RangeEditField.Enable="off";
                case app.P_inButton
                    app.MinP_inButton.Enable="off";
                    app.InputPowerEditField.Enable="off";

            end
        end

        % Selection changed function: PlotButtonGroup
        function PlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotButtonGroup.SelectedObject;
            for button = app.VsButtonGroup.Buttons
                button.Enable="on";
            end
            
            switch selectedButton
                case app.MaxRangeButton
                    app.RangeButton.Enable="off";
                case app.MinP_inButton
                    app.P_inButton.Enable="off";
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            app.delete();
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            AboutDialog();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UnderwaterBackscatterLinkBudgetUIFigure = uifigure('Visible', 'off');
            app.UnderwaterBackscatterLinkBudgetUIFigure.Name = 'Underwater Backscatter Link Budget';
            app.UnderwaterBackscatterLinkBudgetUIFigure.Icon = fullfile(pathToMLAPP, 'resources', 'SK_Logo.jpg');
            app.FileMenu = uimenu(app.UnderwaterBackscatterLinkBudgetUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.UnderwaterBackscatterLinkBudgetUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About...';
            app.OperationSettingsPanel = uipanel(app.UnderwaterBackscatterLinkBudgetUIFigure);
            app.OperationSettingsPanel.Title = 'Operation Settings';
            app.DataBandwidthLabel = uilabel(app.OperationSettingsPanel);
            app.DataBandwidthLabel.Text = 'Data Bandwidth:';
            app.DataBandwidthEditField = uieditfield(app.OperationSettingsPanel, 'numeric');
            app.DataBandwidthEditField.Limits = [0 Inf];
            app.DataBandwidthEditField.Value = 500;
            app.PowerThresholdLabel = uilabel(app.OperationSettingsPanel);
            app.PowerThresholdLabel.Text = 'Power Threshold:';
            app.PowerThresholdEditField = uieditfield(app.OperationSettingsPanel, 'numeric');
            app.PowerThresholdEditField.Tooltip = {'Minimum required power for operating the backscatter node electronics (microcontroller, FPGA, sensors, etc)'};
            app.PowerThresholdEditField.Value = -20;
            app.InputPowerEditFieldLabel_10 = uilabel(app.OperationSettingsPanel);
            app.InputPowerEditFieldLabel_10.Text = 'dB';
            app.SNRThresholdLabel = uilabel(app.OperationSettingsPanel);
            app.SNRThresholdLabel.Text = 'SNR Threshold:';
            app.SNRThresholdEditField = uieditfield(app.OperationSettingsPanel, 'numeric');
            app.SNRThresholdEditField.Value = 6;
            app.BandwidthUnitDropDown = uidropdown(app.OperationSettingsPanel);
            app.BandwidthUnitDropDown.Items = {'Hz', 'kHz', 'MHz'};
            app.BandwidthUnitDropDown.Value = 'Hz';
            app.PowerThresholdUnitDropDown = uidropdown(app.OperationSettingsPanel);
            app.PowerThresholdUnitDropDown.Items = {'W', 'mW', 'uW', 'dBW', 'dBm'};
            app.PowerThresholdUnitDropDown.Value = 'dBm';
            app.AnalysisSettingsPanel = uipanel(app.UnderwaterBackscatterLinkBudgetUIFigure);
            app.AnalysisSettingsPanel.Title = 'Analysis Settings';
            app.FrequencyLabel = uilabel(app.AnalysisSettingsPanel);
            app.FrequencyLabel.Tooltip = {'Transmitter Frequency'};
            app.FrequencyLabel.Text = 'Frequency:';
            app.FrequencyEditField = uieditfield(app.AnalysisSettingsPanel, 'numeric');
            app.FrequencyEditField.Limits = [0 Inf];
            app.FrequencyEditField.Enable = 'off';
            app.FrequencyEditField.Tooltip = {'Transmitter Frequency'};
            app.FrequencyEditField.Value = 30;
            app.FrequencyEditField_2Label_2 = uilabel(app.AnalysisSettingsPanel);
            app.FrequencyEditField_2Label_2.Text = 'Range:';
            app.RangeEditField = uieditfield(app.AnalysisSettingsPanel, 'numeric');
            app.RangeEditField.Limits = [0 Inf];
            app.RangeEditField.Value = 10;
            app.PowerLabel = uilabel(app.AnalysisSettingsPanel);
            app.PowerLabel.Tooltip = {'Input power (P_in) to the transmitter (Tx)'};
            app.PowerLabel.Text = 'Input Power:';
            app.InputPowerEditField = uieditfield(app.AnalysisSettingsPanel, 'numeric');
            app.InputPowerEditField.Tooltip = {'Input power (P_in) to the transmitter (Tx)'};
            app.InputPowerEditField.Value = 1;
            app.FreqUnitDropDown = uidropdown(app.AnalysisSettingsPanel);
            app.FreqUnitDropDown.Items = {'Hz', 'kHz', 'MHz'};
            app.FreqUnitDropDown.Value = 'kHz';
            app.PowerUnitDropDown = uidropdown(app.AnalysisSettingsPanel);
            app.PowerUnitDropDown.Items = {'W', 'mW', 'uW', 'dBW', 'dBm'};
            app.PowerUnitDropDown.Value = 'W';
            app.RangeUnitDropDown = uidropdown(app.AnalysisSettingsPanel);
            app.RangeUnitDropDown.Items = {'m', 'km', 'ft', 'mile'};
            app.RangeUnitDropDown.Value = 'm';
            app.PhysicalSetupPanel = uipanel(app.UnderwaterBackscatterLinkBudgetUIFigure);
            app.PhysicalSetupPanel.Title = 'Physical Setup';
            app.GraphicsPanel = uipanel(app.PhysicalSetupPanel);
            app.HarvestedPowerLabel = uilabel(app.GraphicsPanel);
            app.HarvestedPowerLabel.Text = 'Harvested Power';
            app.Image = uiimage(app.GraphicsPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'resources', 'backscatter.png');
            app.SNRLabel = uilabel(app.GraphicsPanel);
            app.SNRLabel.Tooltip = {'Signal-to-noise ratio'};
            app.SNRLabel.Text = 'SNR';
            app.RLLabel = uilabel(app.GraphicsPanel);
            app.RLLabel.Tooltip = {'Received Pressure Level. Sound pressure incident to the backscatter node.'};
            app.RLLabel.Text = 'RL';
            app.SLLabel = uilabel(app.GraphicsPanel);
            app.SLLabel.Tooltip = {'Source Pressure Level'};
            app.SLLabel.Text = 'SL';
            app.BLLabel = uilabel(app.GraphicsPanel);
            app.BLLabel.Tooltip = {'Backscatter Level'};
            app.BLLabel.Text = 'BL';
            app.PinLabel = uilabel(app.GraphicsPanel);
            app.PinLabel.Tooltip = {'Input power'};
            app.PinLabel.Text = 'Pin';
            app.ConfigTxButton = uibutton(app.PhysicalSetupPanel, 'push');
            app.ConfigTxButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigTxButtonPushed, true);
            app.ConfigTxButton.Visible = 'off';
            app.ConfigTxButton.Text = 'Config.';
            app.ConfigRxButton = uibutton(app.PhysicalSetupPanel, 'push');
            app.ConfigRxButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigRxButtonPushed, true);
            app.ConfigRxButton.Visible = 'off';
            app.ConfigRxButton.Text = 'Config.';
            app.ConfigNodeButton = uibutton(app.PhysicalSetupPanel, 'push');
            app.ConfigNodeButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigNodeButtonPushed, true);
            app.ConfigNodeButton.Visible = 'off';
            app.ConfigNodeButton.Text = 'Config.';
            app.ConfigChannelButton = uibutton(app.PhysicalSetupPanel, 'push');
            app.ConfigChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigChannelButtonPushed, true);
            app.ConfigChannelButton.Text = 'Config. Channel';
            app.TxDropDownLabel = uilabel(app.PhysicalSetupPanel);
            app.TxDropDownLabel.Tooltip = {'Transmitter'};
            app.TxDropDownLabel.Text = 'Tx:';
            app.TxDropDown = uidropdown(app.PhysicalSetupPanel);
            app.TxDropDown.Items = {'Spherical', 'Generic'};
            app.TxDropDown.ValueChangedFcn = createCallbackFcn(app, @TxDropDownValueChanged, true);
            app.TxDropDown.Tooltip = {''};
            app.TxDropDown.Value = 'Spherical';
            app.RxDropDownLabel = uilabel(app.PhysicalSetupPanel);
            app.RxDropDownLabel.Tooltip = {'Receiver'};
            app.RxDropDownLabel.Text = 'Rx:';
            app.RxDropDown = uidropdown(app.PhysicalSetupPanel);
            app.RxDropDown.ValueChangedFcn = createCallbackFcn(app, @RxDropDownValueChanged, true);
            app.RxDropDown.Tooltip = {''};
            app.BackscatternodeDropDownLabel = uilabel(app.PhysicalSetupPanel);
            app.BackscatternodeDropDownLabel.Text = 'Backscatter node:';
            app.NodeDropDown = uidropdown(app.PhysicalSetupPanel);
            app.NodeDropDown.ValueChangedFcn = createCallbackFcn(app, @NodeDropDownValueChanged, true);
            app.ResultsPanel = uipanel(app.UnderwaterBackscatterLinkBudgetUIFigure);
            app.ResultsPanel.Title = 'Results';
            app.downlinkPlot = uiaxes(app.ResultsPanel);
            title(app.downlinkPlot, 'Downlink')
            xlabel(app.downlinkPlot, 'Frequency [kHz]')
            ylabel(app.downlinkPlot, 'Harvested Power [dBm]')
            zlabel(app.downlinkPlot, 'Z')
            app.downlinkPlot.XScale = 'log';
            app.downlinkPlot.YScale = 'log';
            app.uplinkPlot = uiaxes(app.ResultsPanel);
            title(app.uplinkPlot, 'Uplink')
            xlabel(app.uplinkPlot, 'Frequency [kHz]')
            ylabel(app.uplinkPlot, 'SNR')
            zlabel(app.uplinkPlot, 'Z')
            app.uplinkPlot.XScale = 'log';
            app.AnalysisPanel = uipanel(app.UnderwaterBackscatterLinkBudgetUIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.Panel = uipanel(app.AnalysisPanel);
            app.VsLabel = uilabel(app.Panel);
            app.VsLabel.Text = 'Vs';
            app.PlotButtonGroup = uibuttongroup(app.AnalysisPanel);
            app.PlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotButtonGroupSelectionChanged, true);
            app.HarvPowSNRButton = uiradiobutton(app.PlotButtonGroup);
            app.HarvPowSNRButton.Tooltip = {'Harvested Power/SNR'};
            app.HarvPowSNRButton.Text = 'Harv Pow/SNR';
            app.HarvPowSNRButton.Value = true;
            app.MaxRangeButton = uiradiobutton(app.PlotButtonGroup);
            app.MaxRangeButton.Tooltip = {'Maximum operating range for downlink (power) and uplink (communication)'};
            app.MaxRangeButton.Text = 'Max Range';
            app.MinP_inButton = uiradiobutton(app.PlotButtonGroup);
            app.MinP_inButton.Tooltip = {'Minimum input electrical power to the transmitter (Tx)'};
            app.MinP_inButton.Text = 'Min P_in';
            app.VsButtonGroup = uibuttongroup(app.AnalysisPanel);
            app.VsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VsButtonGroupSelectionChanged, true);
            app.FrequencyButton = uiradiobutton(app.VsButtonGroup);
            app.FrequencyButton.Text = 'Frequency';
            app.FrequencyButton.Value = true;
            app.RangeButton = uiradiobutton(app.VsButtonGroup);
            app.RangeButton.Text = 'Range';
            app.P_inButton = uiradiobutton(app.VsButtonGroup);
            app.P_inButton.Tooltip = {'Input power to the transmitter (Tx)'};
            app.P_inButton.Text = 'P_in';
            app.CalculateButton = uibutton(app.UnderwaterBackscatterLinkBudgetUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UnderwaterBackscatterLinkBudgetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Backscatter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UnderwaterBackscatterLinkBudgetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UnderwaterBackscatterLinkBudgetUIFigure)
        end
    end
end