classdef ChannelDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfigureChannelUIFigure       matlab.ui.Figure
        CancelButton                   matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        NoiseCharacteristicsPanel      matlab.ui.container.Panel
        Label_2                        matlab.ui.control.Label
        msLabel_2                      matlab.ui.control.Label
        ShippingactivityEditField      matlab.ui.control.NumericEditField
        ShippingactivityEditField_2Label  matlab.ui.control.Label
        WindSpeedEditField             matlab.ui.control.NumericEditField
        WindSpeedEditFieldLabel        matlab.ui.control.Label
        MediumPropertiesPanel          matlab.ui.container.Panel
        pptLabel                       matlab.ui.control.Label
        CLabel                         matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        kgm3Label                      matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        pHvalueEditField               matlab.ui.control.NumericEditField
        pHvalueEditFieldLabel          matlab.ui.control.Label
        SalinityEditField              matlab.ui.control.NumericEditField
        SalinityEditField_3Label       matlab.ui.control.Label
        TemperatureEditField           matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel      matlab.ui.control.Label
        DepthEditField                 matlab.ui.control.NumericEditField
        DepthEditFieldLabel            matlab.ui.control.Label
        SoundSpeedEditField            matlab.ui.control.NumericEditField
        SoundSpeedEditFieldLabel       matlab.ui.control.Label
        DensityEditField               matlab.ui.control.NumericEditField
        DensityEditFieldLabel          matlab.ui.control.Label
        SpreadingFactorEditField       matlab.ui.control.NumericEditField
        SpreadingFactorEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        caller % Description
    end
    
    methods (Access = public)
        
        function updateDialogParameters(app)
            channel=app.caller.model.channel;
            app.ShippingactivityEditField.Value=channel.shipping;
            app.WindSpeedEditField.Value=channel.wind;
            app.pHvalueEditField.Value=channel.pH;
            app.TemperatureEditField.Value=channel.temp;
            app.DepthEditField.Value=channel.depth;
            app.SoundSpeedEditField.Value=channel.c;
            app.DensityEditField.Value=channel.rho;
            app.SpreadingFactorEditField.Value=channel.k_s; 
            app.SalinityEditField.Value=channel.salinity;
        end
        
        function results = updateCallerParameters(app)
            app.caller.model.channel.shipping=app.ShippingactivityEditField.Value;
            app.caller.model.channel.wind=app.WindSpeedEditField.Value;
            app.caller.model.channel.pH=app.pHvalueEditField.Value;
            app.caller.model.channel.temp=app.TemperatureEditField.Value;
            app.caller.model.channel.depth=app.DepthEditField.Value;
            app.caller.model.channel.c=app.SoundSpeedEditField.Value;
            app.caller.model.channel.rho=app.DensityEditField.Value;
            app.caller.model.channel.k_s=app.SpreadingFactorEditField.Value; 
            app.caller.model.channel.salinity=app.SalinityEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.caller=caller;
            updateDialogParameters(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            updateCallerParameters(app);
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ConfigureChannelUIFigure = uifigure('Visible', 'off');
            app.ConfigureChannelUIFigure.Name = 'Configure Channel';
            app.ConfigureChannelUIFigure.Icon = fullfile(pathToMLAPP, 'resources', 'MIT_logo.png');
            app.MediumPropertiesPanel = uipanel(app.ConfigureChannelUIFigure);
            app.MediumPropertiesPanel.Title = 'Medium Properties';
            app.SpreadingFactorEditFieldLabel = uilabel(app.MediumPropertiesPanel);
            app.SpreadingFactorEditFieldLabel.Text = 'Spreading Factor';
            app.SpreadingFactorEditField = uieditfield(app.MediumPropertiesPanel, 'numeric');
            app.SpreadingFactorEditField.Limits = [0 2];
            app.DensityEditFieldLabel = uilabel(app.MediumPropertiesPanel);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.MediumPropertiesPanel, 'numeric');
            app.DensityEditField.Limits = [0 Inf];
            app.SoundSpeedEditFieldLabel = uilabel(app.MediumPropertiesPanel);
            app.SoundSpeedEditFieldLabel.Text = 'Sound Speed';
            app.SoundSpeedEditField = uieditfield(app.MediumPropertiesPanel, 'numeric');
            app.SoundSpeedEditField.Limits = [0 Inf];
            app.DepthEditFieldLabel = uilabel(app.MediumPropertiesPanel);
            app.DepthEditFieldLabel.Text = 'Depth';
            app.DepthEditField = uieditfield(app.MediumPropertiesPanel, 'numeric');
            app.DepthEditField.Limits = [0 Inf];
            app.TemperatureEditFieldLabel = uilabel(app.MediumPropertiesPanel);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.MediumPropertiesPanel, 'numeric');
            app.SalinityEditField_3Label = uilabel(app.MediumPropertiesPanel);
            app.SalinityEditField_3Label.Text = 'Salinity';
            app.SalinityEditField = uieditfield(app.MediumPropertiesPanel, 'numeric');
            app.pHvalueEditFieldLabel = uilabel(app.MediumPropertiesPanel);
            app.pHvalueEditFieldLabel.Text = 'pH value';
            app.pHvalueEditField = uieditfield(app.MediumPropertiesPanel, 'numeric');
            app.pHvalueEditField.Limits = [0 14];
            app.Label = uilabel(app.MediumPropertiesPanel);
            app.Label.Text = '[1]';
            app.kgm3Label = uilabel(app.MediumPropertiesPanel);
            app.kgm3Label.Text = '[kg/m3]';
            app.msLabel = uilabel(app.MediumPropertiesPanel);
            app.msLabel.Text = '[m/s]';
            app.mLabel = uilabel(app.MediumPropertiesPanel);
            app.mLabel.Text = '[m]';
            app.CLabel = uilabel(app.MediumPropertiesPanel);
            app.CLabel.Text = '[C]';
            app.pptLabel = uilabel(app.MediumPropertiesPanel);
            app.pptLabel.Text = '[ppt]';
            app.NoiseCharacteristicsPanel = uipanel(app.ConfigureChannelUIFigure);
            app.NoiseCharacteristicsPanel.Title = 'Noise Characteristics';
            app.WindSpeedEditFieldLabel = uilabel(app.NoiseCharacteristicsPanel);
            app.WindSpeedEditFieldLabel.Text = 'Wind Speed';
            app.WindSpeedEditField = uieditfield(app.NoiseCharacteristicsPanel, 'numeric');
            app.WindSpeedEditField.Limits = [0 Inf];
            app.ShippingactivityEditField_2Label = uilabel(app.NoiseCharacteristicsPanel);
            app.ShippingactivityEditField_2Label.Text = 'Shipping activity';
            app.ShippingactivityEditField = uieditfield(app.NoiseCharacteristicsPanel, 'numeric');
            app.ShippingactivityEditField.Limits = [0 1];
            app.msLabel_2 = uilabel(app.NoiseCharacteristicsPanel);
            app.msLabel_2.Text = '[m/s]';
            app.Label_2 = uilabel(app.NoiseCharacteristicsPanel);
            app.Label_2.Text = '[1]';
            app.SaveButton = uibutton(app.ConfigureChannelUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.ConfigureChannelUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.ConfigureChannelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChannelDialog(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConfigureChannelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ConfigureChannelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfigureChannelUIFigure)
        end
    end
end