classdef GenericTransducerDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfigureTransducerUIFigure  matlab.ui.Figure
        TransducerRepresentationButtonGroup  matlab.ui.container.ButtonGroup
        TVREditField                 matlab.ui.control.NumericEditField
        TVREditFieldLabel            matlab.ui.control.Label
        dBLabel_3                    matlab.ui.control.Label
        etaEditField                 matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        DIEditField                  matlab.ui.control.NumericEditField
        DIEditFieldLabel             matlab.ui.control.Label
        dBLabel_2                    matlab.ui.control.Label
        GainEditField                matlab.ui.control.NumericEditField
        GainEditFieldLabel           matlab.ui.control.Label
        dBLabel                      matlab.ui.control.Label
        TVRButton                    matlab.ui.control.RadioButton
        DIButton                     matlab.ui.control.RadioButton
        GainButton                   matlab.ui.control.RadioButton
        XEditField                   matlab.ui.control.NumericEditField
        jEditField_3Label            matlab.ui.control.Label
        REditField                   matlab.ui.control.NumericEditField
        ZEditField_3Label            matlab.ui.control.Label
        ZUnit                        matlab.ui.control.Label
        CancelButton                 matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp % Description
        tr
        role
        
    end
    
    methods (Access = private)
        function switchRepresentation(app,selection)
             switch selection
                case "Gain"
                    app.GainEditField.Enable="on";
                    app.DIEditField.Enable="off";
                    app.etaEditField.Enable="off";
                    app.TVREditField.Enable="off";
                    app.REditField.Enable="off";
                    app.XEditField.Enable="off";
                    app.ZUnit.Enable="off";
                 case "DI & η"
                    app.GainEditField.Enable="off";
                    app.DIEditField.Enable="on";
                    app.etaEditField.Enable="on";
                    app.TVREditField.Enable="off";
                    app.REditField.Enable="off";
                    app.XEditField.Enable="off";
                    app.ZUnit.Enable="off";
                 case "TVR"
                    app.GainEditField.Enable="off";
                    app.DIEditField.Enable="off";
                    app.etaEditField.Enable="off";
                    app.TVREditField.Enable="on";
                    app.REditField.Enable="on";
                    app.XEditField.Enable="on";
                    app.ZUnit.Enable="off";
             end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, role)
            app.callingApp=caller;
            app.tr=caller.model.getTr(role);
            app.role=role;
            
            app.GainEditField.Value=app.tr.G(1);
            app.DIEditField.Value=app.tr.DI(1);
            app.etaEditField.Value=app.tr.eta(1)*100;
            app.REditField.Value=real(app.tr.Z(1));
            app.XEditField.Value=imag(app.tr.Z(1));
            app.TVREditField.Value=app.tr.TVR(1);
            
            app.switchRepresentation(app.tr.rep);
            switch app.tr.rep
                case "Gain"
                    app.TransducerRepresentationButtonGroup.Buttons(1).Value=1;
                case "DI & η"
                    app.TransducerRepresentationButtonGroup.Buttons(2).Value=1;
                case "TVR"
                    app.TransducerRepresentationButtonGroup.Buttons(3).Value=1;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            rep=app.TransducerRepresentationButtonGroup.SelectedObject.Text;
            Z=app.REditField.Value+1j*app.XEditField.Value;
            switch rep
                case "Gain"
                    newTr=Transducer(app.role,app.callingApp.model.freq,Z,"Custom",rep,app.GainEditField.Value);
                case "DI & η"
                    newTr=Transducer(app.role,app.callingApp.model.freq,Z,"Custom",rep,app.DIEditField.Value,...
                        app.etaEditField.Value/100);
                case "TVR"
                    newTr=Transducer(app.role,app.callingApp.model.freq,Z,"Custom",rep,app.TVREditField.Value);
            end

            app.callingApp.model.setTr(newTr)

           app.delete();
            
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            value = app.GainEditField.Value;
        end

        % Value changed function: TVREditField
        function TVREditFieldValueChanged(app, event)
            value = app.TVREditField.Value;

        end

        % Selection changed function: TransducerRepresentationButtonGroup
        function TransducerRepresentationButtonGroupSelectionChanged(app, event)
            selectedButton = app.TransducerRepresentationButtonGroup.SelectedObject;
            app.switchRepresentation(selectedButton.Text);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ConfigureTransducerUIFigure = uifigure('Visible', 'off');
            app.ConfigureTransducerUIFigure.Name = 'Configure Transducer';
            app.ConfigureTransducerUIFigure.Icon = fullfile(pathToMLAPP, 'resources', 'MIT_logo.png');
            app.SaveButton = uibutton(app.ConfigureTransducerUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.ConfigureTransducerUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ZUnit = uilabel(app.ConfigureTransducerUIFigure);
            app.ZUnit.Enable = 'off';
            app.ZUnit.Text = '[Ω]';
            app.ZEditField_3Label = uilabel(app.ConfigureTransducerUIFigure);
            app.ZEditField_3Label.Enable = 'off';
            app.ZEditField_3Label.Text = 'Z:';
            app.REditField = uieditfield(app.ConfigureTransducerUIFigure, 'numeric');
            app.REditField.Enable = 'off';
            app.jEditField_3Label = uilabel(app.ConfigureTransducerUIFigure);
            app.jEditField_3Label.Enable = 'off';
            app.jEditField_3Label.Text = '+j';
            app.XEditField = uieditfield(app.ConfigureTransducerUIFigure, 'numeric');
            app.XEditField.Enable = 'off';
            app.TransducerRepresentationButtonGroup = uibuttongroup(app.ConfigureTransducerUIFigure);
            app.TransducerRepresentationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TransducerRepresentationButtonGroupSelectionChanged, true);
            app.TransducerRepresentationButtonGroup.Title = 'Transducer Representation';
            app.GainButton = uiradiobutton(app.TransducerRepresentationButtonGroup);
            app.GainButton.Text = 'Gain';
            app.GainButton.Value = true;
            app.DIButton = uiradiobutton(app.TransducerRepresentationButtonGroup);
            app.DIButton.Text = 'DI & η';
            app.TVRButton = uiradiobutton(app.TransducerRepresentationButtonGroup);
            app.TVRButton.Text = 'TVR';
            app.dBLabel = uilabel(app.TransducerRepresentationButtonGroup);
            app.dBLabel.Text = '[dB]';
            app.GainEditFieldLabel = uilabel(app.TransducerRepresentationButtonGroup);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.TransducerRepresentationButtonGroup, 'numeric');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.dBLabel_2 = uilabel(app.TransducerRepresentationButtonGroup);
            app.dBLabel_2.Text = '[dB]';
            app.DIEditFieldLabel = uilabel(app.TransducerRepresentationButtonGroup);
            app.DIEditFieldLabel.Text = 'DI';
            app.DIEditField = uieditfield(app.TransducerRepresentationButtonGroup, 'numeric');
            app.Label_3 = uilabel(app.TransducerRepresentationButtonGroup);
            app.Label_3.Text = '[%]';
            app.EditFieldLabel = uilabel(app.TransducerRepresentationButtonGroup);
            app.EditFieldLabel.Text = 'η';
            app.etaEditField = uieditfield(app.TransducerRepresentationButtonGroup, 'numeric');
            app.etaEditField.Limits = [0 100];
            app.dBLabel_3 = uilabel(app.TransducerRepresentationButtonGroup);
            app.dBLabel_3.Text = '[dB]';
            app.TVREditFieldLabel = uilabel(app.TransducerRepresentationButtonGroup);
            app.TVREditFieldLabel.Text = 'TVR';
            app.TVREditField = uieditfield(app.TransducerRepresentationButtonGroup, 'numeric');
            app.TVREditField.ValueChangedFcn = createCallbackFcn(app, @TVREditFieldValueChanged, true);
            % Show the figure after all components are created
            app.ConfigureTransducerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GenericTransducerDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConfigureTransducerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfigureTransducerUIFigure)
        end
    end
end