classdef TransducerDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfigureTransducerUIFigure  matlab.ui.Figure
        TransducerEditField          matlab.ui.control.EditField
        TransducerEditFieldLabel     matlab.ui.control.Label
        CloseButton                  matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        ImpedancePlot                matlab.ui.control.UIAxes
        GainPlot                     matlab.ui.control.UIAxes
        TVRPlot                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingApp % Description
        tr
        role
    end
    
    methods (Access = private)


        function updatePlots(app)
            % data=readtable(app.callingApp.folder+"\"+transducerName+".csv");
            transducerData=table(app.tr.freq.',app.tr.TVR.',real(app.tr.Z).',imag(app.tr.Z).');
            app.UITable.Data=transducerData;            
            plot(app.TVRPlot,app.tr.freq/1e3,app.tr.TVR,'LineWidth',1.5)
            plot(app.GainPlot,app.tr.freq/1e3,app.tr.G,"LineWidth",1.5);
            
            yyaxis(app.ImpedancePlot,"Left")
            plot(app.ImpedancePlot,app.tr.freq/1e3,real(app.tr.Z),'LineWidth',1.5)
            yyaxis(app.ImpedancePlot,"Right")
            plot(app.ImpedancePlot,app.tr.freq/1e3,imag(app.tr.Z),'LineWidth',1.5)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, transducerRole)
            app.callingApp=caller;
            app.role=transducerRole;
            app.tr=app.callingApp.model.getTr(transducerRole);
            app.TransducerEditField.Value=app.tr.type;
            app.updatePlots();
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ConfigureTransducerUIFigure = uifigure('Visible', 'off');
            app.ConfigureTransducerUIFigure.Name = 'Configure Transducer';
            app.ConfigureTransducerUIFigure.Icon = fullfile(pathToMLAPP, 'resources', 'MIT_logo.png');
            app.TVRPlot = uiaxes(app.ConfigureTransducerUIFigure);
            title(app.TVRPlot, 'TVR')
            xlabel(app.TVRPlot, 'Frequency [kHz]')
            ylabel(app.TVRPlot, 'TVR')
            zlabel(app.TVRPlot, 'Z')
            app.GainPlot = uiaxes(app.ConfigureTransducerUIFigure);
            title(app.GainPlot, 'Gain')
            xlabel(app.GainPlot, 'Frequency [Hz]')
            ylabel(app.GainPlot, 'G')
            zlabel(app.GainPlot, 'Z')
            app.ImpedancePlot = uiaxes(app.ConfigureTransducerUIFigure);
            title(app.ImpedancePlot, 'Impedance')
            xlabel(app.ImpedancePlot, 'Frequency [kHz]')
            ylabel(app.ImpedancePlot, 'R')
            zlabel(app.ImpedancePlot, 'Z')
            app.UITable = uitable(app.ConfigureTransducerUIFigure);
            app.UITable.ColumnName = {'Frequency [Hz]'; 'TVR [dB]'; 'R [Ω]'; 'X [Ω]'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false false];
            app.CloseButton = uibutton(app.ConfigureTransducerUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TransducerEditFieldLabel = uilabel(app.ConfigureTransducerUIFigure);
            app.TransducerEditFieldLabel.Text = 'Transducer:';
            app.TransducerEditField = uieditfield(app.ConfigureTransducerUIFigure, 'text');
            app.TransducerEditField.Editable = 'off';
            % Show the figure after all components are created
            app.ConfigureTransducerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TransducerDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConfigureTransducerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfigureTransducerUIFigure)
        end
    end
end